classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        RunButton                     matlab.ui.control.Button
        ValueofTEditFieldLabel        matlab.ui.control.Label
        ValueofTEditField             matlab.ui.control.NumericEditField
        ConvergenceDropDownLabel      matlab.ui.control.Label
        ConvergenceDropDown           matlab.ui.control.DropDown
        ValueofEpsilonEditFieldLabel  matlab.ui.control.Label
        ValueofEpsilonEditField       matlab.ui.control.NumericEditField
        BarSliderLabel                matlab.ui.control.Label
        BarSlider                     matlab.ui.control.Slider
    end

    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            rng default
                 norm1 = normrnd(2,1.414,app.ValueofTEditField.Value,1);
                 x=1:1:app.ValueofTEditField.Value;
                for i=1:app.ValueofTEditField.Value
                    a=0;
                    for j=1:i
                        a=a+norm1(j);
                    end
                    y(i)=a/(2*i);
                end
                y1=1+app.ValueofEpsilonEditField.Value;
                y3=1-app.ValueofEpsilonEditField.Value;
                for i=1:app.ValueofTEditField.Value
                    y2(i)=y1;
                    y4(i)=y3;
                end
                x1=app.BarSlider.Value;
                
                plot(app.UIAxes,x,y)
               hold(app.UIAxes)
                plot(app.UIAxes,x,y4)
                % hold(app.UIAxes)
              plot(app.UIAxes,x,y2)
              plot(app.UIAxes,x1)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot of y vs x(Convergence almost sure)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ValueofTEditFieldLabel = uilabel(app.UIFigure);
            app.ValueofTEditFieldLabel.Text = 'Value of T';
            app.ValueofTEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueofTEditField.Limits = [0 10000];
            app.ConvergenceDropDownLabel = uilabel(app.UIFigure);
            app.ConvergenceDropDownLabel.Text = 'Convergence';
            app.ConvergenceDropDown = uidropdown(app.UIFigure);
            app.ConvergenceDropDown.Items = {'Probablity', 'Almost sure'};
            app.ConvergenceDropDown.Value = 'Probablity';
            app.ValueofEpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.ValueofEpsilonEditFieldLabel.Text = 'Value of Epsilon';
            app.ValueofEpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueofEpsilonEditField.Limits = [0 0.5];
            app.BarSliderLabel = uilabel(app.UIFigure);
            app.BarSliderLabel.Text = 'Bar';
            app.BarSlider = uislider(app.UIFigure);
            app.BarSlider.Limits = [0 1000];
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end